classdef PL3GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        SolarFarmTab                 matlab.ui.container.Tab
        PowerSwitchLabel             matlab.ui.control.Label
        PowerSwitch                  matlab.ui.control.ToggleSwitch
        ExactValuesLabel             matlab.ui.control.Label
        CurrentmAEditField_7         matlab.ui.control.NumericEditField
        VoltageVEditField_7Label     matlab.ui.control.Label
        VoltageVEditField_7          matlab.ui.control.NumericEditField
        PowerWEditField_7Label       matlab.ui.control.Label
        PowerWEditField_7            matlab.ui.control.NumericEditField
        CurrentmAEditField_2Label_2  matlab.ui.control.Label
        VoltageGaugeLabel            matlab.ui.control.Label
        VoltageGauge                 matlab.ui.control.Gauge
        CurrentGaugeLabel            matlab.ui.control.Label
        CurrentGauge                 matlab.ui.control.Gauge
        PowerGaugeLabel              matlab.ui.control.Label
        PowerGauge                   matlab.ui.control.Gauge
        UIAxes                       matlab.ui.control.UIAxes
        GraphButton                  matlab.ui.control.Button
        ResistanceLabel              matlab.ui.control.Label
        ResistanceEditField          matlab.ui.control.NumericEditField
        GraphButton_2                matlab.ui.control.Button
        GraphButton_3                matlab.ui.control.Button
        BatteryOutputTab             matlab.ui.container.Tab
        UIAxes_6                     matlab.ui.control.UIAxes
        UIAxes_7                     matlab.ui.control.UIAxes
        UIAxes_8                     matlab.ui.control.UIAxes
        ExactValuesLabel_2           matlab.ui.control.Label
        PowerSwitch_2Label           matlab.ui.control.Label
        PowerSwitch_2                matlab.ui.control.ToggleSwitch
        CurrentmAEditField_8Label    matlab.ui.control.Label
        CurrentmAEditField_8         matlab.ui.control.NumericEditField
        PowerWEditField_8Label       matlab.ui.control.Label
        PowerWEditField_8            matlab.ui.control.NumericEditField
        VoltageVEditField_8Label     matlab.ui.control.Label
        VoltageVEditField_8          matlab.ui.control.NumericEditField
        BatteryChargeGaugeLabel      matlab.ui.control.Label
        BatteryChargeGauge           matlab.ui.control.SemicircularGauge
        VDCLoadTab                   matlab.ui.container.Tab
        UIAxes_9                     matlab.ui.control.UIAxes
        UIAxes_10                    matlab.ui.control.UIAxes
        UIAxes_11                    matlab.ui.control.UIAxes
        ExactValuesLabel_3           matlab.ui.control.Label
        PowerSwitch_3Label           matlab.ui.control.Label
        PowerSwitch_3                matlab.ui.control.ToggleSwitch
        VoltageVEditField_3Label     matlab.ui.control.Label
        VoltageVEditField_3          matlab.ui.control.NumericEditField
        CurrentmAEditField_3Label    matlab.ui.control.Label
        CurrentmAEditField_3         matlab.ui.control.NumericEditField
        PowerWEditField_3Label       matlab.ui.control.Label
        PowerWEditField_3            matlab.ui.control.NumericEditField
        VACLoadTab                   matlab.ui.container.Tab
        UIAxes_12                    matlab.ui.control.UIAxes
        UIAxes_13                    matlab.ui.control.UIAxes
        UIAxes_14                    matlab.ui.control.UIAxes
        ExactValuesLabel_4           matlab.ui.control.Label
        PowerSwitch_4Label           matlab.ui.control.Label
        PowerSwitch_4                matlab.ui.control.ToggleSwitch
        VoltageVEditField_4Label     matlab.ui.control.Label
        VoltageVEditField_4          matlab.ui.control.NumericEditField
        CurrentmAEditField_4Label    matlab.ui.control.Label
        CurrentmAEditField_4         matlab.ui.control.NumericEditField
        PowerWEditField_4Label       matlab.ui.control.Label
        PowerWEditField_4            matlab.ui.control.NumericEditField
        VDCLoadTab_2                 matlab.ui.container.Tab
        UIAxes_15                    matlab.ui.control.UIAxes
        UIAxes_16                    matlab.ui.control.UIAxes
        UIAxes_17                    matlab.ui.control.UIAxes
        ExactValuesLabel_5           matlab.ui.control.Label
        PowerSwitch_5Label           matlab.ui.control.Label
        PowerSwitch_5                matlab.ui.control.ToggleSwitch
        VoltageVEditField_5Label     matlab.ui.control.Label
        VoltageVEditField_5          matlab.ui.control.NumericEditField
        CurrentmAEditField_5Label    matlab.ui.control.Label
        CurrentmAEditField_5         matlab.ui.control.NumericEditField
        PowerWEditField_5Label       matlab.ui.control.Label
        PowerWEditField_5            matlab.ui.control.NumericEditField
        VACLoadTab_2                 matlab.ui.container.Tab
        UIAxes_18                    matlab.ui.control.UIAxes
        UIAxes_19                    matlab.ui.control.UIAxes
        UIAxes_20                    matlab.ui.control.UIAxes
        ExactValuesLabel_6           matlab.ui.control.Label
        PowerSwitch_6Label           matlab.ui.control.Label
        PowerSwitch_6                matlab.ui.control.ToggleSwitch
        VoltageVEditField_6Label     matlab.ui.control.Label
        VoltageVEditField_6          matlab.ui.control.NumericEditField
        CurrentmAEditField_6Label    matlab.ui.control.Label
        CurrentmAEditField_6         matlab.ui.control.NumericEditField
        PowerWEditField_6Label       matlab.ui.control.Label
        PowerWEditField_6            matlab.ui.control.NumericEditField
        MicrogridStatusPanel         matlab.ui.container.Panel
        SolarFarmLampLabel           matlab.ui.control.Label
        SolarFarmLamp                matlab.ui.control.Lamp
        BatteryLampLabel             matlab.ui.control.Label
        BatteryLamp                  matlab.ui.control.Lamp
        VDCLoadLampLabel             matlab.ui.control.Label
        V5DCLoadLamp                 matlab.ui.control.Lamp
        VACLoadLampLabel             matlab.ui.control.Label
        VACLoadLamp                  matlab.ui.control.Lamp
        VDCLoadLamp_2Label           matlab.ui.control.Label
        V24DCLoadLamp                matlab.ui.control.Lamp
        VACLoadLamp_2Label           matlab.ui.control.Label
        V24ACLoadLamp                matlab.ui.control.Lamp
        SystemPowerSwitchLabel       matlab.ui.control.Label
        SystemPowerSwitch            matlab.ui.control.Switch
        BluetoothStatusPanel         matlab.ui.container.Panel
        ConnectionLampLabel          matlab.ui.control.Label
        ConnectionLamp               matlab.ui.control.Lamp
        DisconnectButton             matlab.ui.control.Button
        RemoteIDLabel                matlab.ui.control.Label
        BTIDLabel                    matlab.ui.control.Label
        ConnectButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        Bt;
        count = 0; 
        solarFarmCurrent;
        solarFarmVoltage;
    end
    
    methods (Access = private)          
        
        function bluetoothSetup(app)
            
            % Create bluetooth object
            app.Bt = Bluetooth('btspp://000666EBE248', 1);
            % Open Bluetooth connection
            fopen(app.Bt);
            % Set terminator character
            set(app.Bt, 'Terminator', 'E');
            % Indicate the program starts on the Solar Panel Tab
            fwrite(Bt, '1');
            
            app.ConnectionLamp.Color = 'green';
            app.BTIDLabel.Text = get(app.Bt, 'RemoteID');
            app.BTIDLabel.Visible = 'on';
        end
        
        function readData(app, Bt)
            
            % Set the X axes to automatically update
            app.UIAxes.XLimMode = 'auto';
            
            while(1)
                % Get line of data received via Bluetooth
                data = fgetl(app.Bt);
                % If the data contains a ',' - Parse
                if (contains(data, ','))
                    parseData(app, data);
                elseif (data == '0') 
                    disp(data);
                elseif (data =='1')
                    disp(data);
                end
            end
        end
        
        function startSystem(app)
            % Run bluetooth setup then enable all Microgrid relays
            if (app.ConnectionLamp.Color == 'green')
                readData(app);
            else
                bluetoothSetup(app);
            end
            
            app.PowerSwitch.Value = 'On';
            app.PowerSwitchLamp.Color = 'green';
            writeMCU(app.Bt, '11');
            
            app.PowerSwitch_2.Value = 'On';
            app.BatteryLamp.Color = 'green';
            app.PowerSwitch_3.Value = 'On';
            app.V5DCLoadLamp.Color = 'green';
            app.PowerSwitch_4.Value = 'On';
            app.VACLoadLamp.Color = 'green';
            app.PowerSwitch_5.Value = 'On';
            app.V24DCLoadLamp.Color = 'green';
            app.PowerSwitch_6.Value = 'On';
            app.V24ACLoadLamp.Color = 'green';

        end
        
        
        function stopSystem(app)
                app.PowerSwitch.Value = 'Off';
                app.SolarFarmLamp.Color = 'red';
                app.PowerSwitch_2.Value = 'Off';
                app.BatteryLamp.Color = 'red';
                app.PowerSwitch_3.Value = 'Off';
                app.V5DCLoadLamp.Color = 'red';
                app.PowerSwitch_4.Value = 'Off';
                app.VACLoadLamp.Color = 'red';
                app.PowerSwitch_5.Value = 'Off';
                app.V24DCLoadLamp.Color = 'red';
                app.PowerSwitch_6.Value = 'Off';
                app.V24ACLoadLamp.Color = 'red';
        end
        
        
        function graph(app, x, y)
            plot(app.UIAxes, y, x, '-');
        end
        
        function writeMCU(app, value)
            fwrite(app.Bt, value);
        end
        
        function parseData(app, data, count)
            % Keep plotted values
            hold(app.UIAxes, 'on');
            
            split = strsplit(data, ',');
            
            % Assign values
            value = split{1};
            type = split{2};
            location = split{3};

            if (location == '1' && strcmpi(app.PowerSwitch.Value, 'On') )       % Solar Farm
                % Increment Tick counter
                app.count = app.count + 1;
                if (type == 'A' )        % Current
                    app.solarFarmCurrent = str2double(value);
                    app.CurrentGauge.Value = app.solarFarmCurrent;
                    app.CurrentmAEditField_7.Value =app.solarFarmCurrent;
                    
                elseif (type == 'V')    % Voltage
                    
                    app.solarFarmVoltage = str2double(value);
                    app.VoltageVEditField_7.Value = app.solarFarmVoltage;
                    app.VoltageGauge.Value = app.solarFarmVoltage;
                    
                end
                
                if (app.count > 1 && strcmpi(app.PowerSwitch.Value, 'On'))
                    power = app.solarFarmVoltage*app.solarFarmCurrent;
                    app.PowerWEditField_7.Value = power;
                    app.PowerGauge.Value = power;
                    app.UIAxes.XTickMode = 'auto';
                    app.UIAxes.XLim = [0,100];
                    
                    if(app.count >= 100)
                        app.count = 0;
                        hold(app.UIAxes, 'off');
                    end
                    
                    plot(app.UIAxes, app.count, power, 'bo');
                    drawnow
                    
                    %pause(1);
                end
                
            elseif (location == '2')    % Battery
                
            else
                disp("No Location Identified");
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: SystemPowerSwitch
        function SystemPowerSwitchValueChanged(app, event)
            value = app.SystemPowerSwitch.Value;
                
            if (strcmpi(value,'On'))
%                 app.PowerSwitch.Value = 'On';
%                 app.SolarFarmLamp.Color = 'green';
%                 app.PowerSwitch_2.Value = 'On';
%                 app.BatteryLamp.Color = 'green';
%                 app.PowerSwitch_3.Value = 'On';
%                 app.V5DCLoadLamp.Color = 'green';
%                 app.PowerSwitch_4.Value = 'On';
%                 app.VACLoadLamp.Color = 'green';
%                 app.PowerSwitch_5.Value = 'On';
%                 app.V24DCLoadLamp.Color = 'green';
%                 app.PowerSwitch_6.Value = 'On';
%                 app.V24ACLoadLamp.Color = 'green';
                 startSystem(app);
            else 
                stopSystem(app);
            end     
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            Bt = Bluetooth('btspp://000666EBE248', 1);  
            fclose(Bt);
            delete(Bt);
            clear Bt;
            app.ConnectionLamp.Color = 'red';
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            bluetoothSetup(app);
            readData(app, app.Bt);
        end

        % Value changed function: PowerSwitch
        function PowerSwitchValueChanged(app, event)
            value = app.PowerSwitch.Value;  % Solar Panel Status
            if (strcmpi(value,'On'))
                if (strcmpi(app.SystemPowerSwitch.Value, 'On'))
                    app.SolarFarmLamp.Color = 'green';
                    value = 'On';
                    writeMCU(app, "11");
                end
            else
                app.SolarFarmLamp.Color = 'red';
                value = 'Off';
                writeMCU(app, 10);
            end
        end

        % Value changed function: PowerSwitch_2
        function PowerSwitch_2ValueChanged(app, event)
            value = app.PowerSwitch_2.Value;
            if (strcmpi(value,'On'))
                if (strcmpi(app.SystemPowerSwitch.Value, 'On'))
                    app.BatteryLamp.Color = 'green';
                end
            else
                app.BatteryLamp.Color = 'red';
            end
        end

        % Value changed function: PowerSwitch_3
        function PowerSwitch_3ValueChanged(app, event)
            value = app.PowerSwitch_3.Value;
            if (strcmpi(value,'On'))
                if (strcmpi(app.SystemPowerSwitch.Value, 'On'))
                    app.V5DCLoadLamp.Color = 'green';
                end
            else
                app.V5DCLoadLamp.Color = 'red';
            end
        end

        % Value changed function: PowerSwitch_4
        function PowerSwitch_4ValueChanged(app, event)
            value = app.PowerSwitch_4.Value;
            if (strcmpi(value,'On'))
                if (strcmpi(app.SystemPowerSwitch.Value, 'On'))
                    app.VACLoadLamp.Color = 'green';
                end
            else
                app.VACLoadLamp.Color = 'red';
            end
        end

        % Value changed function: PowerSwitch_5
        function PowerSwitch_5ValueChanged(app, event)
            value = app.PowerSwitch_5.Value;
            if (strcmpi(value,'On'))
                if (strcmpi(app.SystemPowerSwitch.Value, 'On'))
                    app.V24DCLoadLamp.Color = 'green';
                end
            else
                app.V24DCLoadLamp.Color = 'red';
            end
        end

        % Value changed function: PowerSwitch_6
        function PowerSwitch_6ValueChanged(app, event)
            value = app.PowerSwitch_6.Value;
            if (strcmpi(value,'On'))
                if (strcmpi(app.SystemPowerSwitch.Value, 'On'))
                    app.V24ACLoadLamp.Color = 'green';
                end
            else
                app.V24ACLoadLamp.Color = 'red';
            end
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            % Open graphing figure
            % Plot Solar Farm Voltage over time
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SolarFarmTab = uitab(app.TabGroup);
            app.SolarFarmTab.Title = 'Solar Farm';
            app.PowerSwitchLabel = uilabel(app.SolarFarmTab);
            app.PowerSwitchLabel.Text = 'Power';
            app.PowerSwitch = uiswitch(app.SolarFarmTab, 'toggle');
            app.PowerSwitch.ValueChangedFcn = createCallbackFcn(app, @PowerSwitchValueChanged, true);
            app.ExactValuesLabel = uilabel(app.SolarFarmTab);
            app.ExactValuesLabel.Text = 'Exact Values';
            app.CurrentmAEditField_7 = uieditfield(app.SolarFarmTab, 'numeric');
            app.VoltageVEditField_7Label = uilabel(app.SolarFarmTab);
            app.VoltageVEditField_7Label.Text = 'Voltage (V)';
            app.VoltageVEditField_7 = uieditfield(app.SolarFarmTab, 'numeric');
            app.PowerWEditField_7Label = uilabel(app.SolarFarmTab);
            app.PowerWEditField_7Label.Text = 'Power (W)';
            app.PowerWEditField_7 = uieditfield(app.SolarFarmTab, 'numeric');
            app.CurrentmAEditField_2Label_2 = uilabel(app.SolarFarmTab);
            app.CurrentmAEditField_2Label_2.Text = 'Current (mA)';
            app.VoltageGaugeLabel = uilabel(app.SolarFarmTab);
            app.VoltageGaugeLabel.Text = 'Voltage';
            app.VoltageGauge = uigauge(app.SolarFarmTab, 'circular');
            app.VoltageGauge.Limits = [0 20];
            app.VoltageGauge.MajorTicks = [0 5 10 15 20 25];
            app.CurrentGaugeLabel = uilabel(app.SolarFarmTab);
            app.CurrentGaugeLabel.Text = 'Current';
            app.CurrentGauge = uigauge(app.SolarFarmTab, 'circular');
            app.CurrentGauge.Limits = [0 6];
            app.CurrentGauge.MajorTicks = [0 1 2 3 4 5 6];
            app.PowerGaugeLabel = uilabel(app.SolarFarmTab);
            app.PowerGaugeLabel.Text = 'Power';
            app.PowerGauge = uigauge(app.SolarFarmTab, 'circular');
            app.PowerGauge.MajorTicks = [0 20 40 60 80 100 120];
            app.UIAxes = uiaxes(app.SolarFarmTab);
            title(app.UIAxes, 'Power')
            xlabel(app.UIAxes, 'Ticks')
            ylabel(app.UIAxes, 'W')
            app.UIAxes.YLim = [0 125];
            app.UIAxes.XTick = [0 1];
            app.UIAxes.YTick = [0 25 50 75 100 125];
            app.UIAxes.YTickLabel = {'0'; '25'; '50'; '75'; '100'; '125'};
            app.GraphButton = uibutton(app.SolarFarmTab, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.ResistanceLabel = uilabel(app.SolarFarmTab);
            app.ResistanceLabel.Text = 'Resistance (Ω)';
            app.ResistanceEditField = uieditfield(app.SolarFarmTab, 'numeric');
            app.ResistanceEditField.Value = 100;
            app.GraphButton_2 = uibutton(app.SolarFarmTab, 'push');
            app.GraphButton_2.Text = 'Graph';
            app.GraphButton_3 = uibutton(app.SolarFarmTab, 'push');
            app.GraphButton_3.Text = 'Graph';
            app.BatteryOutputTab = uitab(app.TabGroup);
            app.BatteryOutputTab.Title = 'Battery Output';
            app.UIAxes_6 = uiaxes(app.BatteryOutputTab);
            title(app.UIAxes_6, 'Power')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            app.UIAxes_7 = uiaxes(app.BatteryOutputTab);
            title(app.UIAxes_7, 'Current')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            app.UIAxes_8 = uiaxes(app.BatteryOutputTab);
            title(app.UIAxes_8, 'Voltage')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            app.ExactValuesLabel_2 = uilabel(app.BatteryOutputTab);
            app.ExactValuesLabel_2.Text = 'Exact Values';
            app.PowerSwitch_2Label = uilabel(app.BatteryOutputTab);
            app.PowerSwitch_2Label.Text = 'Power';
            app.PowerSwitch_2 = uiswitch(app.BatteryOutputTab, 'toggle');
            app.PowerSwitch_2.ValueChangedFcn = createCallbackFcn(app, @PowerSwitch_2ValueChanged, true);
            app.CurrentmAEditField_8Label = uilabel(app.BatteryOutputTab);
            app.CurrentmAEditField_8Label.Text = 'Current (mA)';
            app.CurrentmAEditField_8 = uieditfield(app.BatteryOutputTab, 'numeric');
            app.PowerWEditField_8Label = uilabel(app.BatteryOutputTab);
            app.PowerWEditField_8Label.Text = 'Power (W)';
            app.PowerWEditField_8 = uieditfield(app.BatteryOutputTab, 'numeric');
            app.VoltageVEditField_8Label = uilabel(app.BatteryOutputTab);
            app.VoltageVEditField_8Label.Text = 'Voltage (V)';
            app.VoltageVEditField_8 = uieditfield(app.BatteryOutputTab, 'numeric');
            app.BatteryChargeGaugeLabel = uilabel(app.BatteryOutputTab);
            app.BatteryChargeGaugeLabel.Text = 'Battery Charge';
            app.BatteryChargeGauge = uigauge(app.BatteryOutputTab, 'semicircular');
            app.BatteryChargeGauge.Limits = [0 20];
            app.VDCLoadTab = uitab(app.TabGroup);
            app.VDCLoadTab.Title = '5V DC Load';
            app.UIAxes_9 = uiaxes(app.VDCLoadTab);
            title(app.UIAxes_9, 'Power')
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            app.UIAxes_10 = uiaxes(app.VDCLoadTab);
            title(app.UIAxes_10, 'Current')
            xlabel(app.UIAxes_10, 'X')
            ylabel(app.UIAxes_10, 'Y')
            app.UIAxes_11 = uiaxes(app.VDCLoadTab);
            title(app.UIAxes_11, 'Voltage')
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            app.ExactValuesLabel_3 = uilabel(app.VDCLoadTab);
            app.ExactValuesLabel_3.Text = 'Exact Values';
            app.PowerSwitch_3Label = uilabel(app.VDCLoadTab);
            app.PowerSwitch_3Label.Text = 'Power';
            app.PowerSwitch_3 = uiswitch(app.VDCLoadTab, 'toggle');
            app.PowerSwitch_3.ValueChangedFcn = createCallbackFcn(app, @PowerSwitch_3ValueChanged, true);
            app.VoltageVEditField_3Label = uilabel(app.VDCLoadTab);
            app.VoltageVEditField_3Label.Text = 'Voltage (V)';
            app.VoltageVEditField_3 = uieditfield(app.VDCLoadTab, 'numeric');
            app.CurrentmAEditField_3Label = uilabel(app.VDCLoadTab);
            app.CurrentmAEditField_3Label.Text = 'Current (mA)';
            app.CurrentmAEditField_3 = uieditfield(app.VDCLoadTab, 'numeric');
            app.PowerWEditField_3Label = uilabel(app.VDCLoadTab);
            app.PowerWEditField_3Label.Text = 'Power (W)';
            app.PowerWEditField_3 = uieditfield(app.VDCLoadTab, 'numeric');
            app.VACLoadTab = uitab(app.TabGroup);
            app.VACLoadTab.Title = '5V AC Load';
            app.UIAxes_12 = uiaxes(app.VACLoadTab);
            title(app.UIAxes_12, 'Power')
            xlabel(app.UIAxes_12, 'X')
            ylabel(app.UIAxes_12, 'Y')
            app.UIAxes_13 = uiaxes(app.VACLoadTab);
            title(app.UIAxes_13, 'Current')
            xlabel(app.UIAxes_13, 'X')
            ylabel(app.UIAxes_13, 'Y')
            app.UIAxes_14 = uiaxes(app.VACLoadTab);
            title(app.UIAxes_14, 'Voltage')
            xlabel(app.UIAxes_14, 'X')
            ylabel(app.UIAxes_14, 'Y')
            app.ExactValuesLabel_4 = uilabel(app.VACLoadTab);
            app.ExactValuesLabel_4.Text = 'Exact Values';
            app.PowerSwitch_4Label = uilabel(app.VACLoadTab);
            app.PowerSwitch_4Label.Text = 'Power';
            app.PowerSwitch_4 = uiswitch(app.VACLoadTab, 'toggle');
            app.PowerSwitch_4.ValueChangedFcn = createCallbackFcn(app, @PowerSwitch_4ValueChanged, true);
            app.VoltageVEditField_4Label = uilabel(app.VACLoadTab);
            app.VoltageVEditField_4Label.Text = 'Voltage (V)';
            app.VoltageVEditField_4 = uieditfield(app.VACLoadTab, 'numeric');
            app.CurrentmAEditField_4Label = uilabel(app.VACLoadTab);
            app.CurrentmAEditField_4Label.Text = 'Current (mA)';
            app.CurrentmAEditField_4 = uieditfield(app.VACLoadTab, 'numeric');
            app.PowerWEditField_4Label = uilabel(app.VACLoadTab);
            app.PowerWEditField_4Label.Text = 'Power (W)';
            app.PowerWEditField_4 = uieditfield(app.VACLoadTab, 'numeric');
            app.VDCLoadTab_2 = uitab(app.TabGroup);
            app.VDCLoadTab_2.Title = '24V DC Load';
            app.UIAxes_15 = uiaxes(app.VDCLoadTab_2);
            title(app.UIAxes_15, 'Power')
            xlabel(app.UIAxes_15, 'X')
            ylabel(app.UIAxes_15, 'Y')
            app.UIAxes_16 = uiaxes(app.VDCLoadTab_2);
            title(app.UIAxes_16, 'Current')
            xlabel(app.UIAxes_16, 'X')
            ylabel(app.UIAxes_16, 'Y')
            app.UIAxes_17 = uiaxes(app.VDCLoadTab_2);
            title(app.UIAxes_17, 'Voltage')
            xlabel(app.UIAxes_17, 'X')
            ylabel(app.UIAxes_17, 'Y')
            app.ExactValuesLabel_5 = uilabel(app.VDCLoadTab_2);
            app.ExactValuesLabel_5.Text = 'Exact Values';
            app.PowerSwitch_5Label = uilabel(app.VDCLoadTab_2);
            app.PowerSwitch_5Label.Text = 'Power';
            app.PowerSwitch_5 = uiswitch(app.VDCLoadTab_2, 'toggle');
            app.PowerSwitch_5.ValueChangedFcn = createCallbackFcn(app, @PowerSwitch_5ValueChanged, true);
            app.VoltageVEditField_5Label = uilabel(app.VDCLoadTab_2);
            app.VoltageVEditField_5Label.Text = 'Voltage (V)';
            app.VoltageVEditField_5 = uieditfield(app.VDCLoadTab_2, 'numeric');
            app.CurrentmAEditField_5Label = uilabel(app.VDCLoadTab_2);
            app.CurrentmAEditField_5Label.Text = 'Current (mA)';
            app.CurrentmAEditField_5 = uieditfield(app.VDCLoadTab_2, 'numeric');
            app.PowerWEditField_5Label = uilabel(app.VDCLoadTab_2);
            app.PowerWEditField_5Label.Text = 'Power (W)';
            app.PowerWEditField_5 = uieditfield(app.VDCLoadTab_2, 'numeric');
            app.VACLoadTab_2 = uitab(app.TabGroup);
            app.VACLoadTab_2.Title = '24V AC Load';
            app.UIAxes_18 = uiaxes(app.VACLoadTab_2);
            title(app.UIAxes_18, 'Power')
            xlabel(app.UIAxes_18, 'X')
            ylabel(app.UIAxes_18, 'Y')
            app.UIAxes_19 = uiaxes(app.VACLoadTab_2);
            title(app.UIAxes_19, 'Current')
            xlabel(app.UIAxes_19, 'X')
            ylabel(app.UIAxes_19, 'Y')
            app.UIAxes_20 = uiaxes(app.VACLoadTab_2);
            title(app.UIAxes_20, 'Voltage')
            xlabel(app.UIAxes_20, 'X')
            ylabel(app.UIAxes_20, 'Y')
            app.ExactValuesLabel_6 = uilabel(app.VACLoadTab_2);
            app.ExactValuesLabel_6.Text = 'Exact Values';
            app.PowerSwitch_6Label = uilabel(app.VACLoadTab_2);
            app.PowerSwitch_6Label.Text = 'Power';
            app.PowerSwitch_6 = uiswitch(app.VACLoadTab_2, 'toggle');
            app.PowerSwitch_6.ValueChangedFcn = createCallbackFcn(app, @PowerSwitch_6ValueChanged, true);
            app.VoltageVEditField_6Label = uilabel(app.VACLoadTab_2);
            app.VoltageVEditField_6Label.Text = 'Voltage (V)';
            app.VoltageVEditField_6 = uieditfield(app.VACLoadTab_2, 'numeric');
            app.CurrentmAEditField_6Label = uilabel(app.VACLoadTab_2);
            app.CurrentmAEditField_6Label.Text = 'Current (mA)';
            app.CurrentmAEditField_6 = uieditfield(app.VACLoadTab_2, 'numeric');
            app.PowerWEditField_6Label = uilabel(app.VACLoadTab_2);
            app.PowerWEditField_6Label.Text = 'Power (W)';
            app.PowerWEditField_6 = uieditfield(app.VACLoadTab_2, 'numeric');
            app.MicrogridStatusPanel = uipanel(app.UIFigure);
            app.MicrogridStatusPanel.Title = 'Microgrid Status';
            app.SolarFarmLampLabel = uilabel(app.MicrogridStatusPanel);
            app.SolarFarmLampLabel.Text = 'Solar Farm';
            app.SolarFarmLamp = uilamp(app.MicrogridStatusPanel);
            app.BatteryLampLabel = uilabel(app.MicrogridStatusPanel);
            app.BatteryLampLabel.Text = 'Battery';
            app.BatteryLamp = uilamp(app.MicrogridStatusPanel);
            app.VDCLoadLampLabel = uilabel(app.MicrogridStatusPanel);
            app.VDCLoadLampLabel.Text = '5V DC Load';
            app.V5DCLoadLamp = uilamp(app.MicrogridStatusPanel);
            app.VACLoadLampLabel = uilabel(app.MicrogridStatusPanel);
            app.VACLoadLampLabel.Text = '5V AC Load';
            app.VACLoadLamp = uilamp(app.MicrogridStatusPanel);
            app.VDCLoadLamp_2Label = uilabel(app.MicrogridStatusPanel);
            app.VDCLoadLamp_2Label.Text = '24V DC Load';
            app.V24DCLoadLamp = uilamp(app.MicrogridStatusPanel);
            app.VACLoadLamp_2Label = uilabel(app.MicrogridStatusPanel);
            app.VACLoadLamp_2Label.Text = '24V AC Load';
            app.V24ACLoadLamp = uilamp(app.MicrogridStatusPanel);
            app.SystemPowerSwitchLabel = uilabel(app.UIFigure);
            app.SystemPowerSwitchLabel.Text = 'System Power';
            app.SystemPowerSwitch = uiswitch(app.UIFigure, 'slider');
            app.SystemPowerSwitch.ValueChangedFcn = createCallbackFcn(app, @SystemPowerSwitchValueChanged, true);
            app.BluetoothStatusPanel = uipanel(app.UIFigure);
            app.BluetoothStatusPanel.Title = 'Bluetooth Status';
            app.ConnectionLampLabel = uilabel(app.BluetoothStatusPanel);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.BluetoothStatusPanel);
            app.DisconnectButton = uibutton(app.BluetoothStatusPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.RemoteIDLabel = uilabel(app.BluetoothStatusPanel);
            app.RemoteIDLabel.Text = 'RemoteID:';
            app.BTIDLabel = uilabel(app.BluetoothStatusPanel);
            app.BTIDLabel.Visible = 'off';
            app.BTIDLabel.Text = 'BTID';
            app.ConnectButton = uibutton(app.BluetoothStatusPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PL3GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end